classdef solarPlanner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SolarPlanner                matlab.ui.Figure
        Consumo                     matlab.ui.control.Table
        UIAxes                      matlab.ui.control.UIAxes
        Procurar                    matlab.ui.control.Button
        File                        matlab.ui.control.EditField
        Exportar                    matlab.ui.control.Button
        Limpar                      matlab.ui.control.Button
        LatitudeSpinnerLabel        matlab.ui.control.Label
        Latitude                    matlab.ui.control.Spinner
        reaNecessriaEditFieldLabel  matlab.ui.control.Label
        Area                        matlab.ui.control.EditField
        EficinciaFotovoltaicaLabel  matlab.ui.control.Label
        Eff                         matlab.ui.control.Spinner
        UIAxes2                     matlab.ui.control.UIAxes
        ParmetrosFsicos             matlab.ui.control.Label
        HistricodeConsumo           matlab.ui.control.Label
        ResultadoButtonGroup        matlab.ui.container.ButtonGroup
        xyz                         matlab.ui.control.RadioButton
        dirInc                      matlab.ui.control.RadioButton
        EnergiaEditFieldLabel       matlab.ui.control.Label
        Energia                     matlab.ui.control.NumericEditField
        OrientaoTextAreaLabel       matlab.ui.control.Label
        Orientacao                  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        N % vetor da orientação
        E % energia / area
        dt=60 % in seconds 
        mi=log(1.368e3/1000) % absorção atmosférica
        Winc=1.368e3 % incidência solar a 1 AU de distância do Sol
        cloudiness=0.4 % fator arbitrário para simular nebulosidade média
        opts % opções de fmincon para otimização 
        model % Description
        A % area necessaria
    end
    
    methods (Access = private)
        
        function app = optimizeOrientation(app)
            toRad=@(deg) deg*pi/180;
            nToVec=@(n) [cos(toRad(n(1))).*sin(toRad(n(2))) sin(toRad(n(1))).*sin(toRad(n(2))) cos(toRad(n(2)))];
            [R,~]=calcSunPath(app.Latitude.Value);
            Efunc=@(n) -sum(R.*reshape(nToVec(n),[1 1 3]).*exp(-app.mi./R(:,:,3)),'all','omitnan').*app.Eff.Value.*app.Winc.*(1-app.cloudiness).*app.dt./360000000;
            [app.N,Eoptim]=fmincon(Efunc,[90 0],[],[],[],[],[0 0],[360 90],[],app.opts);
            app.E=-Eoptim;
            app.Energia.Value=-Eoptim;
        end
        
        function app = updatePanelPlot(app)
            toRad=@(deg) deg*pi/180;
            nToVec=@(n) [cos(toRad(n(1))).*sin(toRad(n(2))) sin(toRad(n(1))).*sin(toRad(n(2))) cos(toRad(n(2)))];
            panel=.33*[-1 -1 0; 1 -1 0; 1 1 0; -1 1 0];
            n=app.N;
            u=cross(nToVec(n),[0;0;1]);
            u=u./sqrt(u*u');
            theta=-acos([0 0 1]*nToVec(n)');
            Rtheta=rotmat(u,theta);
            panel=(Rtheta*panel')';
            dz=-min(panel(:,3));
            panel=panel+[0 0 dz];
            
            cla(app.UIAxes2)
            hold(app.UIAxes2,'on')
            [x,y]=meshgrid(1*[-1 1],1*[-1 1]);
            ground=surf(app.UIAxes2,x,y,zeros(size(x)),'EdgeColor','none','FaceColor',[0 0.5 0],'FaceLighting','gouraud');
            ppPanel=patch(app.UIAxes2,'Faces',[1 2 3 4],'Vertices',panel,'FaceColor',0.25*[0 0.5 1],'FaceLighting','gouraud');
            material(ppPanel,'shiny')
            material(ground,'dull')
            daspect(app.UIAxes2,[1 1 1])
            zlim(app.UIAxes2,[0 1])
            light(app.UIAxes2,'Position',nToVec(n),'Style','infinite','Color',[0.9 1 0.8])
            xlabel(app.UIAxes2,'')
            ylabel(app.UIAxes2,'')
            zlabel(app.UIAxes2,'')
            title(app.UIAxes2,'')
            app.UIAxes2.XTick=[];
            app.UIAxes2.YTick=[];
            app.UIAxes2.ZTick=[];
            box(app.UIAxes2,'on')
            grid(app.UIAxes2,'on')
            view(app.UIAxes2,[-60 45])
            quiver3(app.UIAxes2,0,0,0,1,0,0,'Color','r','LineWidth',5,'MaxHeadSize',0.5)
        end
        
        
        function app = displayOrientationAsVector(app)
            toRad=@(deg) deg*pi/180;
            nToVec=@(n) [cos(toRad(n(1))).*sin(toRad(n(2))) sin(toRad(n(1))).*sin(toRad(n(2))) cos(toRad(n(2)))];
            app.Orientacao.Value=strjoin(cellstr(num2str(nToVec(app.N)','%5.3f')),'\n');
        end
        
        
        function app = updateUsagePlot(app)
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.Consumo.Data.Mes,app.Consumo.Data.Consumo,'rx')
            x=(0:0.01:max(app.Consumo.Data.Mes))';
            plot(app.UIAxes,x,predict(app.model,x),'b:')
            plot(app.UIAxes,app.Consumo.Data.Mes,predict(app.model,app.Consumo.Data.Mes),'bo')
            legend(app.UIAxes,["dado" "fit"])
            app.UIAxes.XTick=1:12;
            app.UIAxes.XTickLabel=["Jan" "Feb" "Mar" "Apr" "May" "Jun" "Jul" "Aug" "Sep" "Oct" "Nov" "Dec"];
            xlabel(app.UIAxes,'Mês')
            ylabel(app.UIAxes,'Consumo (kWh)')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.opts=optimoptions('fmincon','Display',"none");
            app=optimizeOrientation(app);
            app=updatePanelPlot(app);
            if app.dirInc.Value
                app.Orientacao.Value=strjoin(cellstr(num2str(app.N','%5.2f')),'\n');
            else
                app=displayOrientationAsVector(app);
            end
        end

        % Selection changed function: ResultadoButtonGroup
        function ResultadoButtonGroupSelectionChanged(app, event)
            if app.dirInc.Value
                app.Orientacao.Value=strjoin(cellstr(num2str(app.N','%5.2f')),'\n');
            else
                app=displayOrientationAsVector(app);
            end
        end

        % Value changed function: Eff, Latitude
        function LatOrEffValueChanged(app, event)
            app=optimizeOrientation(app);
            app=updatePanelPlot(app);
            if app.dirInc.Value
                app.Orientacao.Value=strjoin(cellstr(num2str(app.N','%5.2f')),'\n');
            else
                app=displayOrientationAsVector(app);
            end
            if ~isempty(app.Consumo.Data)
                app.A=sum(app.Consumo.Data.Fit)./app.Energia.Value;
                app.Area.Value=num2str(app.A,'%5.3f');
            end
        end

        % Button pushed function: Procurar
        function ProcurarButtonPushed(app, event)
            [file,Fpath]=uigetfile('./*.xlsx');
            app.File.Value=[Fpath file];
            ds=datastore(app.File.Value);
            T=readall(ds);
            if width(T)~=2 || height(T)~=12
                error('Tabela deve ter 2 colunas 12 linhas e cabeçalho de 1 linha')
            else
                T.Properties.VariableNames={'Mes','Consumo'};
                m1=(max(T.Consumo)-min(T.Consumo))./2;
                m2=min(T.Consumo)+m1;
                X=[0;T.Mes];
                Y=[T.Consumo(end);T.Consumo];
                fittedModel=fitnlm(X,Y,@(b,x) b(1)+b(2).*sin(x.*pi/6+b(3)),...
                        [m2 m1 0]);
                T.Fit=predict(fittedModel,T.Mes);
                app.Consumo.Data=T;
                app.A=sum(app.Consumo.Data.Fit)./app.Energia.Value;
                app.Area.Value=num2str(app.A,'%5.3f');
                app.model=fittedModel;
                app=updateUsagePlot(app);
                app.Exportar.Enable='on';
            end
        end

        % Button pushed function: Exportar
        function ExportarButtonPushed(app, event)
            s.Consumo=app.Consumo.Data;
            s.Latitude=app.Latitude.Value;
            s.Eff=app.Eff.Value;
            s.Energia=app.Energia.Value;
            s.Orientacao=app.N;
            s.Area=app.A;
            s.Model=app.model;
            uisave('s')
        end

        % Button pushed function: Limpar
        function LimparButtonPushed(app, event)
            app.Latitude.Value=23.5;
            app.Eff.Value=16.5;
            app.dirInc.Value=1;
            app.Consumo.Data=[];
            cla(app.UIAxes)
            app.Area.Value=num2str(0,'%5.3f');
            app=optimizeOrientation(app);
            app=updatePanelPlot(app);
            if app.dirInc.Value
                app.Orientacao.Value=strjoin(cellstr(num2str(app.N','%5.2f')),'\n');
            else
                app=displayOrientationAsVector(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SolarPlanner = uifigure('Visible', 'off');
            app.SolarPlanner.Name = 'UI Figure';
            app.Consumo = uitable(app.SolarPlanner);
            app.Consumo.ColumnName = {'Mes'; 'Consumo'; 'Fit'};
            app.Consumo.RowName = {};
            app.UIAxes = uiaxes(app.SolarPlanner);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Procurar = uibutton(app.SolarPlanner, 'push');
            app.Procurar.ButtonPushedFcn = createCallbackFcn(app, @ProcurarButtonPushed, true);
            app.Procurar.Tooltip = {'Procure um arquivo de histórico de consumo; o arquivo deve conter 2 colunas 2 13 linhas (incluindo linha de cabeçalho)'};
            app.Procurar.Text = 'Procurar...';
            app.File = uieditfield(app.SolarPlanner, 'text');
            app.File.Editable = 'off';
            app.Exportar = uibutton(app.SolarPlanner, 'push');
            app.Exportar.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.Exportar.Enable = 'off';
            app.Exportar.Text = 'Exportar';
            app.Limpar = uibutton(app.SolarPlanner, 'push');
            app.Limpar.ButtonPushedFcn = createCallbackFcn(app, @LimparButtonPushed, true);
            app.Limpar.Text = 'Limpar';
            app.LatitudeSpinnerLabel = uilabel(app.SolarPlanner);
            app.LatitudeSpinnerLabel.Text = 'Latitude';
            app.Latitude = uispinner(app.SolarPlanner);
            app.Latitude.Step = 0.1;
            app.Latitude.Limits = [-90 90];
            app.Latitude.ValueDisplayFormat = '%5.3g';
            app.Latitude.ValueChangedFcn = createCallbackFcn(app, @LatOrEffValueChanged, true);
            app.Latitude.Tooltip = {'Latitude do Local (em graus de -90 a 90)'};
            app.Latitude.Value = 23.5;
            app.reaNecessriaEditFieldLabel = uilabel(app.SolarPlanner);
            app.reaNecessriaEditFieldLabel.Text = 'Área Necessária';
            app.Area = uieditfield(app.SolarPlanner, 'text');
            app.Area.Editable = 'off';
            app.Area.Tooltip = {'Área necessária para suprir o consumo ajustado (em metros quadrados).'};
            app.EficinciaFotovoltaicaLabel = uilabel(app.SolarPlanner);
            app.EficinciaFotovoltaicaLabel.Text = 'Eficiência Fotovoltaica (%)';
            app.Eff = uispinner(app.SolarPlanner);
            app.Eff.Step = 0.1;
            app.Eff.Limits = [0 100];
            app.Eff.ValueDisplayFormat = '%5.3g';
            app.Eff.ValueChangedFcn = createCallbackFcn(app, @LatOrEffValueChanged, true);
            app.Eff.Tooltip = {'Eficiência fotovoltaica do painel usado, de 0 a 100%. 16.5% é um valor típico. Os melhores paineis disponíveis alcançam 40%'};
            app.Eff.Value = 16.5;
            app.UIAxes2 = uiaxes(app.SolarPlanner);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.ParmetrosFsicos = uilabel(app.SolarPlanner);
            app.ParmetrosFsicos.Text = 'Parâmetros Físicos';
            app.HistricodeConsumo = uilabel(app.SolarPlanner);
            app.HistricodeConsumo.Text = 'Histórico de Consumo';
            app.ResultadoButtonGroup = uibuttongroup(app.SolarPlanner);
            app.ResultadoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ResultadoButtonGroupSelectionChanged, true);
            app.ResultadoButtonGroup.Title = 'Resultado';
            app.xyz = uiradiobutton(app.ResultadoButtonGroup);
            app.xyz.Text = 'vetor [x y z]';
            app.dirInc = uiradiobutton(app.ResultadoButtonGroup);
            app.dirInc.Text = 'direção e inclinação';
            app.dirInc.Value = true;
            app.EnergiaEditFieldLabel = uilabel(app.ResultadoButtonGroup);
            app.EnergiaEditFieldLabel.Text = 'Energia';
            app.Energia = uieditfield(app.ResultadoButtonGroup, 'numeric');
            app.Energia.Editable = 'off';
            app.Energia.Tooltip = {'Eergia produzida por área de painel na orientação calculada (kWh/m^2) em 1 ano.'};
            app.OrientaoTextAreaLabel = uilabel(app.ResultadoButtonGroup);
            app.OrientaoTextAreaLabel.Text = 'Orientação';
            app.Orientacao = uitextarea(app.ResultadoButtonGroup);
            app.Orientacao.Editable = 'off';
            app.Orientacao.Tooltip = {'Orientação do painel solar otimizada para maximizar a produção de energia.'};
            % Show the figure after all components are created
            app.SolarPlanner.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = solarPlanner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SolarPlanner)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SolarPlanner)
        end
    end
end